classdef Test_Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartControlButton             matlab.ui.control.Button
        Label_Timev                    matlab.ui.control.Label
        StopModelButton                matlab.ui.control.Button
        TimeLabel                      matlab.ui.control.Label
        Label_Rollv                    matlab.ui.control.Label
        Label_Pitchv                   matlab.ui.control.Label
        Label_Yawv                     matlab.ui.control.Label
        Label_Status                   matlab.ui.control.Label
        SimulinkModelStatusLabel       matlab.ui.control.Label
        GlidingAngleEditFieldLabel     matlab.ui.control.Label
        Edit_GlidingAngle              matlab.ui.control.NumericEditField
        DesiredDepthEditFieldLabel     matlab.ui.control.Label
        Edit_DesiredDepth              matlab.ui.control.NumericEditField
        DesiredTimeEditFieldLabel      matlab.ui.control.Label
        Edit_DesiredTime               matlab.ui.control.NumericEditField
        GenerateControlParametersButton  matlab.ui.control.Button
        ControlPitchLabel              matlab.ui.control.Label
        Edit_ControlPitch              matlab.ui.control.NumericEditField
        ControlmassLabel               matlab.ui.control.Label
        Edit_ControlMass               matlab.ui.control.NumericEditField
        IPEditFieldLabel               matlab.ui.control.Label
        Edit_IP                        matlab.ui.control.EditField
        ConnectButton                  matlab.ui.control.Button
        Label_Connected                matlab.ui.control.Label
        UploadControlParametersButton  matlab.ui.control.Button
        ControlTimeLabel               matlab.ui.control.Label
        Edit_ControlTime               matlab.ui.control.NumericEditField
        UIAxes_yaw                     matlab.ui.control.UIAxes
        UIAxes_pitch                   matlab.ui.control.UIAxes
        UIAxes_roll                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        sample_time
        
        Timer
        Timer_time
        
        PlotLine_Roll
        PlotLine_Pitch
        PlotLine_Yaw
        
        model_name
        
        beagle
        
        connected
    end
    
    methods (Access = public)
        
        
        function Timerfnc(app,~,~)
            
            
            time_rto = get_param(join([app.model_name,'/Scope_time']),'RuntimeObject');

            added_x=time_rto.InputPort(1).Data;
            added_x_roll=added_x;
            added_x_pitch=added_x_roll;
            added_x_yaw=added_x_roll;
            
            data_rto = get_param(join([app.model_name,'/Scope_angles']),'RuntimeObject');

            added_y=data_rto.InputPort(1).Data;
            
            added_y_roll=added_y(1);
            added_y_pitch=added_y(2);
            added_y_yaw=added_y(3);
            
            app.Label_Timev.Text=num2str(round(added_x));
            app.Label_Rollv.Text=num2str(round(added_y_roll));
            app.Label_Pitchv.Text=num2str(round(added_y_pitch));
            app.Label_Yawv.Text=num2str(round(added_y_yaw));
            
            x_data_roll=app.PlotLine_Roll.XData;
            x_data_pitch=app.PlotLine_Pitch.XData;
            x_data_yaw=app.PlotLine_Yaw.XData;
            
            y_data_roll=app.PlotLine_Roll.YData;
            y_data_pitch=app.PlotLine_Pitch.YData;
            y_data_yaw=app.PlotLine_Yaw.YData;
            
            
            if(app.Timer_time==0)
                x_data_roll=[];
                x_data_pitch=[];
                x_data_yaw=[];
                
                y_data_roll=[];
                y_data_pitch=[];
                y_data_yaw=[];
                
            end
            
            len_x=length(x_data_roll);
            x_data_roll(len_x+1)=added_x_roll;
            x_data_pitch(len_x+1)=added_x_pitch;
            x_data_yaw(len_x+1)=added_x_yaw;
            
            len_y=length(y_data_roll);
            y_data_roll(len_y+1)=added_y_roll;
            y_data_pitch(len_y+1)=added_y_pitch;
            y_data_yaw(len_y+1)=added_y_yaw;
            
            x_min_roll=min(x_data_roll);
            x_max_roll=max(x_data_roll); 
            y_min_roll=min(y_data_roll);
            y_max_roll=max(y_data_roll);
            
            x_min_pitch=min(x_data_pitch);
            x_max_pitch=max(x_data_pitch); 
            y_min_pitch=min(y_data_pitch);
            y_max_pitch=max(y_data_pitch);
            
            x_min_yaw=min(x_data_yaw);
            x_max_yaw=max(x_data_yaw); 
            y_min_yaw=min(y_data_yaw);
            y_max_yaw=max(y_data_yaw);
         
            if(app.Timer_time==0)          
            else
                app.UIAxes_roll.XLim = [ceil(x_max_roll)-60 ceil(x_max_roll)];
                app.UIAxes_pitch.XLim = [ceil(x_max_pitch)-60 ceil(x_max_pitch)];
                app.UIAxes_yaw.XLim = [ceil(x_max_yaw)-60 ceil(x_max_yaw)];


                app.UIAxes_roll.XTick=max(ceil(x_max_roll)-60):10:ceil(x_max_roll);
                app.UIAxes_pitch.XTick=max(ceil(x_max_pitch)-60):10:ceil(x_max_pitch);
                app.UIAxes_yaw.XTick=max(ceil(x_max_yaw)-60):10:ceil(x_max_yaw);

            end                   
            
            app.PlotLine_Roll.XData=x_data_roll;
            app.PlotLine_Roll.YData=y_data_roll;
            
            app.PlotLine_Pitch.XData=x_data_pitch;
            app.PlotLine_Pitch.YData=y_data_pitch;
            
            app.PlotLine_Yaw.XData=x_data_yaw;
            app.PlotLine_Yaw.YData=y_data_yaw;

            app.Timer_time=app.Timer_time+app.sample_time;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connected=0;
            
            app.model_name='test';
            
            app.Timer_time=0;
            app.sample_time=1;
            
            app.UIAxes_roll.XLim = [-60 0];
            app.UIAxes_roll.YLim = [-90 90];
            app.UIAxes_roll.XTick=-60:10:0;
            app.UIAxes_roll.YTick=-90:15:90;
            
            app.UIAxes_pitch.XLim = [-60 0];
            app.UIAxes_pitch.YLim = [-90 90];
            app.UIAxes_pitch.XTick=-60:10:0;
            app.UIAxes_pitch.YTick=-90:15:90;
            
            app.UIAxes_yaw.XLim = [-60 0];
            app.UIAxes_yaw.YLim = [-90 90];
            app.UIAxes_yaw.XTick=-60:10:0;
            app.UIAxes_yaw.YTick=-90:15:90;
            
            app.PlotLine_Roll=plot(app.UIAxes_roll,0,0,'-');
            app.PlotLine_Pitch=plot(app.UIAxes_pitch,0,0,'-');
            app.PlotLine_Yaw=plot(app.UIAxes_yaw,0,0,'-');
            
            app.Timer=timer('ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                          'Period', app.sample_time, ...                     % Period is 1 second
                          'BusyMode', 'queue',...              % Queue timer callbacks when busy
                          'TimerFcn', @app.Timerfnc);
            
            
        end

        % Button pushed function: StartControlButton
        function StartControlButtonPushed(app, event)
 
            set_param(app.model_name,'SimulationCommand','start');
            app.Label_Status.Text='Running';
            app.Label_Status.FontColor=[0,1,0];
            
            if strcmp(app.Timer.Running, 'off')
               start(app.Timer);
            end
            
        end

        % Button pushed function: StopModelButton
        function StopModelButtonPushed(app, event)
            
            stop(app.Timer);
            
            set_param(app.model_name,'SimulationCommand','stop');
            app.Label_Status.Text='Loaded, Not Running';
            app.Label_Status.FontColor=[1,1,0];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
%             set_param(app.model_name,'SimulationCommand','stop');
            if(slreportgen.utils.isModelLoaded(app.model_name))
                save_system(app.model_name);
                close_system(app.model_name);
            end
            
            stop(app.Timer);
            delete(app.Timer);
            delete(app)
            
        end

        % Button pushed function: GenerateControlParametersButton
        function GenerateControlParametersButtonPushed(app, event)
            
            depth=app.Edit_DesiredDepth.Value;
            t=app.Edit_DesiredTime.Value;
            lambda=app.Edit_GlidingAngle.Value*pi/180;
            
            d= depth/sin(lambda);
            v=d/t;
            
            syms a m tet
            
            g=8.81; kd0=2.15; kd=25; kl0=0; kl=132.5;
            sol=vpasolve([m*g*sin(tet)+(-kd0+kl0*a+(kl-kd)*a*a)*v*v==0, ...
                        m*g*cos(tet)+(-kl0-(kd0+kl)*a+(-kd)*a*a*a)*v*v==0,...
                        tet+a==lambda],[m,tet,a]);
            teta=sol.tet*(180/pi);
            m0=sol.m;
            
            app.Edit_ControlPitch.Value=double(teta);
            app.Edit_ControlMass.Value=double(m0);
            app.Edit_ControlTime.Value=double(t);
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            try
                app.beagle=beagleboneblue(app.Edit_IP.Value);
                app.connected=1;
            catch ME
                disp(ME);
                fig = uifigure;
                message = sprintf('Couldn''t Connect! \n Consider Changing IP.');
                uialert(fig,message,'Warning','Icon','warning');
            end
            if(app.connected)
                load_system(app.model_name);
                app.Label_Status.Text='Loaded, Not Running';
                app.Label_Status.FontColor=[1,1,0];
                app.Label_Connected.Text='Connected';
                app.Label_Connected.FontColor=[0,1,0];
                app.Edit_IP.Enable=false;
                app.ConnectButton.Enable=false;
            end
            
        end

        % Button pushed function: UploadControlParametersButton
        function UploadControlParametersButtonPushed(app, event)
            if(slreportgen.utils.isModelLoaded(app.model_name))
                
                set_param(join([app.model_name,'/Pitch_desired']),'Value',num2str(app.Edit_ControlPitch.Value));
                set_param(join([app.model_name,'/Mass_desired']),'Value',num2str(app.Edit_ControlMass.Value));
                set_param(join([app.model_name,'/Time_desired']),'Value',num2str(app.Edit_ControlTime.Value));
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartControlButton = uibutton(app.UIFigure, 'push');
            app.StartControlButton.ButtonPushedFcn = createCallbackFcn(app, @StartControlButtonPushed, true);
            app.StartControlButton.Text = 'Start Control';
            app.Label_Timev = uilabel(app.UIFigure);
            app.Label_Timev.Text = '0';
            app.StopModelButton = uibutton(app.UIFigure, 'push');
            app.StopModelButton.ButtonPushedFcn = createCallbackFcn(app, @StopModelButtonPushed, true);
            app.StopModelButton.Text = 'Stop Model';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time:';
            app.Label_Rollv = uilabel(app.UIFigure);
            app.Label_Rollv.Text = '0';
            app.Label_Pitchv = uilabel(app.UIFigure);
            app.Label_Pitchv.Text = '0';
            app.Label_Yawv = uilabel(app.UIFigure);
            app.Label_Yawv.Text = '0';
            app.Label_Status = uilabel(app.UIFigure);
            app.Label_Status.Text = 'Not Loaded, Not Running';
            app.SimulinkModelStatusLabel = uilabel(app.UIFigure);
            app.SimulinkModelStatusLabel.Text = 'Simulink Model Status:';
            app.GlidingAngleEditFieldLabel = uilabel(app.UIFigure);
            app.GlidingAngleEditFieldLabel.Text = 'Gliding Angle';
            app.Edit_GlidingAngle = uieditfield(app.UIFigure, 'numeric');
            app.Edit_GlidingAngle.Limits = [-90 90];
            app.DesiredDepthEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredDepthEditFieldLabel.Text = 'Desired Depth';
            app.Edit_DesiredDepth = uieditfield(app.UIFigure, 'numeric');
            app.Edit_DesiredDepth.Limits = [0 10];
            app.DesiredTimeEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredTimeEditFieldLabel.Text = 'Desired Time';
            app.Edit_DesiredTime = uieditfield(app.UIFigure, 'numeric');
            app.Edit_DesiredTime.Limits = [0 Inf];
            app.GenerateControlParametersButton = uibutton(app.UIFigure, 'push');
            app.GenerateControlParametersButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateControlParametersButtonPushed, true);
            app.GenerateControlParametersButton.Text = 'Generate Control Parameters';
            app.ControlPitchLabel = uilabel(app.UIFigure);
            app.ControlPitchLabel.Text = 'Control Pitch';
            app.Edit_ControlPitch = uieditfield(app.UIFigure, 'numeric');
            app.ControlmassLabel = uilabel(app.UIFigure);
            app.ControlmassLabel.Text = 'Control mass';
            app.Edit_ControlMass = uieditfield(app.UIFigure, 'numeric');
            app.IPEditFieldLabel = uilabel(app.UIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.Edit_IP = uieditfield(app.UIFigure, 'text');
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Label_Connected = uilabel(app.UIFigure);
            app.Label_Connected.Text = 'Not Connected';
            app.UploadControlParametersButton = uibutton(app.UIFigure, 'push');
            app.UploadControlParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UploadControlParametersButtonPushed, true);
            app.UploadControlParametersButton.Text = 'Upload Control Parameters';
            app.ControlTimeLabel = uilabel(app.UIFigure);
            app.ControlTimeLabel.Text = 'Control Time';
            app.Edit_ControlTime = uieditfield(app.UIFigure, 'numeric');
            app.Edit_ControlTime.Limits = [0 Inf];
            app.UIAxes_yaw = uiaxes(app.UIFigure);
            title(app.UIAxes_yaw, 'Yaw')
            xlabel(app.UIAxes_yaw, 't')
            ylabel(app.UIAxes_yaw, 'yaw angle')
            zlabel(app.UIAxes_yaw, 'Z')
            app.UIAxes_yaw.XLim = [-60 0];
            app.UIAxes_yaw.YLim = [-90 90];
            app.UIAxes_yaw.XTick = [-60 -50 -40 -30 -20 -10 0];
            app.UIAxes_yaw.YTick = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.UIAxes_pitch = uiaxes(app.UIFigure);
            title(app.UIAxes_pitch, 'Pitch')
            xlabel(app.UIAxes_pitch, 't')
            ylabel(app.UIAxes_pitch, 'pitch angle')
            zlabel(app.UIAxes_pitch, 'Z')
            app.UIAxes_pitch.XLim = [-60 0];
            app.UIAxes_pitch.YLim = [-90 90];
            app.UIAxes_pitch.XTick = [-60 -50 -40 -30 -20 -10 0];
            app.UIAxes_pitch.YTick = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.UIAxes_roll = uiaxes(app.UIFigure);
            title(app.UIAxes_roll, 'Roll')
            xlabel(app.UIAxes_roll, 't')
            ylabel(app.UIAxes_roll, 'roll angle')
            zlabel(app.UIAxes_roll, 'Z')
            app.UIAxes_roll.XLim = [-60 0];
            app.UIAxes_roll.YLim = [-90 90];
            app.UIAxes_roll.XTick = [-60 -50 -40 -30 -20 -10 0];
            app.UIAxes_roll.YTick = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test_Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end